classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlayButton                     matlab.ui.control.Button
        YourEarningsTextArea           matlab.ui.control.TextArea
        YourEarningsTextAreaLabel      matlab.ui.control.Label
        error_message_window           matlab.ui.control.TextArea
        BettingAmountEditField         matlab.ui.control.NumericEditField
        BettingAmountEditFieldLabel    matlab.ui.control.Label
        MinimumBettingAmountEditField  matlab.ui.control.NumericEditField
        MinimumBettingAmountEditFieldLabel  matlab.ui.control.Label
        Image7                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        Image5                         matlab.ui.control.Image
        Image4                         matlab.ui.control.Image
        DealersHandLabel               matlab.ui.control.Label
        YourHandLabel                  matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        SplitButton                    matlab.ui.control.Button
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        StayButton                     matlab.ui.control.Button
        HitButton                      matlab.ui.control.Button
        WelcometoOnlineBlackjackLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MinimumBettingAmountEditField
        function MinimumBettingAmountEditFieldValueChanged2(app, event)
            value = app.MinimumBettingAmountEditField.Value;
            minimum_bet = app.MinimumBettingAmountEditField.Value;
            placed_bet = app.BettingAmountEditField.Value;
            if placed_bet < 0
                app.error_message_window.Value = 'Please enter a valid betting amount before hitting play.  ';
            elseif placed_bet < minimum_bet
                app.error_message_window.Value = 'Please enter a value over the minimum betting amount before hitting play.';
            else
                app.error_message_window.Value = '';
            end
        end

        % Value changed function: BettingAmountEditField
        function BettingAmountEditFieldValueChanged(app, event)
            placed_bet = app.BettingAmountEditField.Value;
            minimum_bet = app.MinimumBettingAmountEditField.Value;
            if placed_bet < 0
                app.error_message_window.Value = 'Please enter a valid betting amount before hitting play.  ';
            elseif placed_bet < minimum_bet
                app.error_message_window.Value = 'Please enter a value over the minimum betting amount before hitting play.';
            else
                app.error_message_window.Value = '';
            end
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            card_selector = [1 1 2 3 4; 2 1 1 1 1; 3 1 1 1 1; 4 1 1 1 1; 5 1 1 1 1; 6 1 1 1 1; 7 1 1 1 1; 8 1 1 1 1; 9 1 1 1 1; 10 1 1 1 1; 11 1 1 1 1; 12 1 1 1 1; 13 1 1 1 1];
            [value, suit] = size(card_selector);
            count = 0;
            row_index = 1 : value;
            column_index = 1 : suit;
            user_card_value = randsample(row_index, 1);
            user_suit_value = randsample(column_index, 1);
            %card_value_and_suit = card_selector(user_card_value, user_suit_value);
            card_selector(user_card_value, user_suit_value) = 0;
            if card_selector(user_card_value, user_suit_value) == 0
                [value, suit] = size(card_selector);
                row_index = 1 : value;
                column_index = 1 : suit;
                user_card_value = randsample(row_index, 1);
                user_suit_value = randsample(column_index, 1);
                card_selector(user_card_value, user_suit_value) = 0;
                count = count + user_card_value; 
            end
            if count > 21
                app.error_message_window.Value = 'You went over 21; therefore, you lost the game :(';
            elseif count == 21
                app.error_message_window.Value = 'Congratulations, you got a Blackjack! ';
                %app.YourEarningsTextArea = 1.5 * placed_ bet;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            card_selector = [1 1 2 3 4; 2 1 1 1 1; 3 1 1 1 1; 4 1 1 1 1; 5 1 1 1 1; 6 1 1 1 1; 7 1 1 1 1; 8 1 1 1 1; 9 1 1 1 1; 10 1 1 1 1; 11 1 1 1 1; 12 1 1 1 1; 13 1 1 1 1];
            [value, suit] = size(card_selector);
            count = 0;
            row_index = 1 : value;
            column_index = 1 : suit;
            user_card_value = randsample(row_index, 1);
            user_suit_value = randsample(column_index, 1);
            %card_value_and_suit = card_selector(user_card_value, user_suit_value);
            card_selector(user_card_value, user_suit_value) = 0;
            if card_selector(user_card_value, user_suit_value) == 0
                [value, suit] = size(card_selector);
                row_index = 1 : value;
                column_index = 1 : suit;
                user_card_value = randsample(row_index, 1);
                user_suit_value = randsample(column_index, 1);
                card_selector(user_card_value, user_suit_value) = 0;
                count = count + user_card_value; 
            end
            if count > 21
                app.error_message_window.Value = 'You went over 21; therefore, you lost the game :(';
            elseif count == 21
                app.error_message_window.Value = 'Congratulations, you got a Blackjack! ';
                %app.YourEarningsTextArea = 1.5 * placed_ bet;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcometoOnlineBlackjackLabel = uilabel(app.UIFigure);
            app.WelcometoOnlineBlackjackLabel.Text = 'Welcome to Online Blackjack!';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StayButton = uibutton(app.UIFigure, 'push');
            app.StayButton.Text = 'Stay';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image3 = uiimage(app.UIFigure);
            app.SplitButton = uibutton(app.UIFigure, 'push');
            app.SplitButton.Text = 'Split';
            app.Image_2 = uiimage(app.UIFigure);
            app.YourHandLabel = uilabel(app.UIFigure);
            app.YourHandLabel.Text = 'Your Hand';
            app.DealersHandLabel = uilabel(app.UIFigure);
            app.DealersHandLabel.Text = 'Dealer''s Hand';
            app.Image4 = uiimage(app.UIFigure);
            app.Image5 = uiimage(app.UIFigure);
            app.Image6 = uiimage(app.UIFigure);
            app.Image7 = uiimage(app.UIFigure);
            app.MinimumBettingAmountEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumBettingAmountEditFieldLabel.Text = 'Minimum Betting Amount';
            app.MinimumBettingAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumBettingAmountEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumBettingAmountEditFieldValueChanged2, true);
            app.MinimumBettingAmountEditField.Value = -6;
            app.BettingAmountEditFieldLabel = uilabel(app.UIFigure);
            app.BettingAmountEditFieldLabel.Text = 'Betting Amount';
            app.BettingAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.BettingAmountEditField.ValueChangedFcn = createCallbackFcn(app, @BettingAmountEditFieldValueChanged, true);
            app.BettingAmountEditField.Value = -6;
            app.error_message_window = uitextarea(app.UIFigure);
            app.error_message_window.Value = {'Good Luck!'};
            app.YourEarningsTextAreaLabel = uilabel(app.UIFigure);
            app.YourEarningsTextAreaLabel.Text = 'Your Earnings';
            app.YourEarningsTextArea = uitextarea(app.UIFigure);
            app.YourEarningsTextArea.Value = {'0'; '0'};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end